classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Slider                         matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        RestartButton                  matlab.ui.control.Button
        def                            matlab.ui.control.Image
        L                              matlab.ui.control.Image
        Loser                          matlab.ui.control.Image
        YoulooseGitgudscrubBigLButton  matlab.ui.control.Button
        Fire                           matlab.ui.control.Image
        BananaScore                    matlab.ui.control.Label
        BananasDevoured                matlab.ui.control.Label
        Banana                         matlab.ui.control.Image
        Monke                          matlab.ui.control.Image
        Button_up                      matlab.ui.control.Button
        Button_down                    matlab.ui.control.Button
        Button_right                   matlab.ui.control.Button
        Button_left                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        ContextMenu                    matlab.ui.container.ContextMenu
        SliderMenu                     matlab.ui.container.Menu
        WhyMenu                        matlab.ui.container.Menu
        IdontknowMenu                  matlab.ui.container.Menu
        WhatisgoingonMenu              matlab.ui.container.Menu
        EndmysufferingMenu             matlab.ui.container.Menu
        IdesidedeathssweetembraceMenu  matlab.ui.container.Menu
        SlidesMenu                     matlab.ui.container.Menu
        SlippersMenu                   matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        SSSMenu                        matlab.ui.container.Menu
    end

    properties (Access = private)
        Property % Description
        devouringCounter=0;
        moves=0;
        score=0;
        k=0;
    end
    
    methods (Access = private)

        function Checker(app)
            app.moves=app.moves+1;
            app.Fire.Position(2)=app.Fire.Position(2)-5;

            if (app.Monke.Position(1)>=app.Banana.Position(1)-80 && app.Monke.Position(1)<=app.Banana.Position(1)+80)...
                    && (app.Monke.Position(2)>=app.Banana.Position(2)-60 && app.Monke.Position(2)<=app.Banana.Position(2)+60)
                
                BananaCollision(app);
            end

            if (app.Monke.Position(1)>=app.Fire.Position(1)-90 && app.Monke.Position(1)<=app.Fire.Position(1)+90)...
                    && (app.Monke.Position(2)>=app.Fire.Position(2)-100 && app.Monke.Position(2)<=app.Fire.Position(2)+100)...
                    ||(app.Fire.Position(2)<80)
                
                FireCollision(app);
            end
        end
        
        function BananaCollision(app)
            
            app.Banana.Position(1) = round(20+rand(1,1)*(520-20), -1);
            app.Banana.Position(2) = round(80+rand(1,1)*(340-80), -1);

            app.Fire.Position(2)=app.Fire.Position(2)+50;

            Scorer(app);
        end

        function FireCollision(app)
            app.YoulooseGitgudscrubBigLButton.Visible=true;
            app.Loser.Visible=true;
            app.L.Visible=true;
            app.def.Visible=true;
            app.RestartButton.Visible=true;

        end
        
        function Scorer(app)
            app.devouringCounter=app.devouringCounter+1;
            app.score=round(app.score + app.devouringCounter/app.moves);
            app.BananasDevoured.Text = "Bananas Devoured= "+app.devouringCounter;
            app.BananaScore.Text = "Banana Score= "+app.score;
            app.moves=0;
        end 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)

        end

        % Callback function
        function Button2Pushed(app, event)

        end

        % Callback function
        function EditFieldValueChanged(app, event)

        end

        % Callback function
        function SliderValueChanged(app, event)

        end

        % Callback function
        function MenuSelected(app, event)

        end

        % Button pushed function: Button_up
        function Button_upPushed(app, event)
            app.Monke.Position(2)=app.Monke.Position(2)+10; 
            Checker(app);
        end

        % Button pushed function: Button_left
        function Button_leftPushed2(app, event)
            app.Monke.Position(1)=app.Monke.Position(1)-10;
            Checker(app);
        end

        % Button pushed function: Button_down
        function Button_downPushed(app, event)
            app.Monke.Position(2)=app.Monke.Position(2)-10;
            Checker(app);
        end

        % Button pushed function: Button_right
        function Button_rightPushed(app, event)
             app.Monke.Position(1)=app.Monke.Position(1)+10;
             Checker(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if key == "uparrow" || key == "w"
                Button_upPushed(app,key);
            elseif key == "downarrow" || key == "s"
                Button_downPushed(app,key);
            elseif key == "leftarrow" || key == "a"
                Button_leftPushed2(app,key);
            elseif key == "rightarrow" || key == "d"
                Button_rightPushed(app,key);
            end
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)

            if app.k<6
                app.k=app.k+1;
                app.RestartButton.Position(1) = round(20+rand(1,1)*(520-20), -1);
                app.RestartButton.Position(2) = round(80+rand(1,1)*(340-80), -1);
                app.RestartButton.Text="Loser";
                   
            else

                app.devouringCounter=0;
                app.score=0;
                app.moves=0;
                app.k=0;

                app.Monke.Position(1) = 250;
                app.Monke.Position(2) = 80;

                app.Banana.Position(1)=250;
                app.Banana.Position(2)=200;

                app.Fire.Position(1)=310;
                app.Fire.Position(2)=440;

                app.YoulooseGitgudscrubBigLButton.Visible=false;
                app.Loser.Visible=false;
                app.L.Visible=false;
                app.def.Visible=false;
                app.RestartButton.Visible=false;
            end         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'jungle.jpg';
            app.Button_left = uibutton(app.UIFigure, 'push');
            app.Button_left.ButtonPushedFcn = createCallbackFcn(app, @Button_leftPushed2, true);
            app.Button_left.Text = '←';
            app.Button_right = uibutton(app.UIFigure, 'push');
            app.Button_right.ButtonPushedFcn = createCallbackFcn(app, @Button_rightPushed, true);
            app.Button_right.Text = '→';
            app.Button_down = uibutton(app.UIFigure, 'push');
            app.Button_down.ButtonPushedFcn = createCallbackFcn(app, @Button_downPushed, true);
            app.Button_down.Text = '↓';
            app.Button_up = uibutton(app.UIFigure, 'push');
            app.Button_up.ButtonPushedFcn = createCallbackFcn(app, @Button_upPushed, true);
            app.Button_up.Text = '↑';
            app.Monke = uiimage(app.UIFigure);
            app.Monke.ImageSource = 'thumb.jpg';
            app.Banana = uiimage(app.UIFigure);
            app.Banana.ImageSource = 'rotating-banana-meme-rotating-banana.gif';
            app.BananasDevoured = uilabel(app.UIFigure);
            app.BananasDevoured.Text = 'Bananas Devoured=0';
            app.BananaScore = uilabel(app.UIFigure);
            app.BananaScore.Text = 'Banana Score= 0';
            app.Fire = uiimage(app.UIFigure);
            app.Fire.ImageSource = 'fire.jpg';
            app.YoulooseGitgudscrubBigLButton = uibutton(app.UIFigure, 'push');
            app.YoulooseGitgudscrubBigLButton.Visible = 'off';
            app.YoulooseGitgudscrubBigLButton.Text = {'You loose'; 'Git gud scrub'; 'Big L'};
            app.Loser = uiimage(app.UIFigure);
            app.Loser.Visible = 'off';
            app.Loser.ImageSource = 'lost.gif';
            app.L = uiimage(app.UIFigure);
            app.L.Visible = 'off';
            app.L.ImageSource = 'takeTheL.gif';
            app.def = uiimage(app.UIFigure);
            app.def.Visible = 'off';
            app.def.ImageSource = 'def.gif';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Visible = 'off';
            app.RestartButton.Text = 'Restart';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SliderMenu = uimenu(app.ContextMenu);
            app.SliderMenu.Text = 'Slider';
            app.WhyMenu = uimenu(app.SliderMenu);
            app.WhyMenu.Text = 'Why?';
            app.IdontknowMenu = uimenu(app.WhyMenu);
            app.IdontknowMenu.Text = 'I don''t know';
            app.WhatisgoingonMenu = uimenu(app.WhyMenu);
            app.WhatisgoingonMenu.Text = 'What is going on?';
            app.EndmysufferingMenu = uimenu(app.SliderMenu);
            app.EndmysufferingMenu.Text = 'End my suffering';
            app.IdesidedeathssweetembraceMenu = uimenu(app.EndmysufferingMenu);
            app.IdesidedeathssweetembraceMenu.Text = 'I deside deaths sweet embrace';
            app.SlidesMenu = uimenu(app.ContextMenu);
            app.SlidesMenu.Text = 'Slides';
            app.SlippersMenu = uimenu(app.SlidesMenu);
            app.SlippersMenu.Text = 'Slippers';
            app.Menu = uimenu(app.SlidesMenu);
            app.Menu.Text = 'Menu';
            app.SSSMenu = uimenu(app.Menu);
            app.SSSMenu.Text = 'SSS';
            % Assign app.ContextMenu
            app.Slider.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end